classdef mobilemineSim < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        GridLayout2                    matlab.ui.container.GridLayout
        TabGroup                       matlab.ui.container.TabGroup
        SimulationTab                  matlab.ui.container.Tab
        AnimateCheckBox                matlab.ui.control.CheckBox
        StartButton                    matlab.ui.control.Button
        TimeLimitsEditField            matlab.ui.control.NumericEditField
        TimeLimitsEditFieldLabel       matlab.ui.control.Label
        NumRunsEdit                    matlab.ui.control.NumericEditField
        RunsLabel                      matlab.ui.control.Label
        FleetTab                       matlab.ui.container.Tab
        DisplayShipHeadingCheckBox     matlab.ui.control.CheckBox
        FleetBehaviorMenu              matlab.ui.control.DropDown
        BehaviorDropDown_2Label        matlab.ui.control.Label
        NumShipsEdit                   matlab.ui.control.NumericEditField
        ShipsLabel                     matlab.ui.control.Label
        MinefieldTab                   matlab.ui.container.Tab
        MineTypeDropDown               matlab.ui.control.DropDown
        MineTypeDropDownLabel          matlab.ui.control.Label
        DetectionRangemEditField       matlab.ui.control.NumericEditField
        DetectionRangemEditFieldLabel  matlab.ui.control.Label
        LayoutDropDown                 matlab.ui.control.DropDown
        LayoutDropDownLabel            matlab.ui.control.Label
        DamageRangemEditField          matlab.ui.control.NumericEditField
        DamageRangemEditFieldLabel     matlab.ui.control.Label
        MineCountEditField             matlab.ui.control.NumericEditField
        MineCountEditFieldLabel        matlab.ui.control.Label
        HTML                           matlab.ui.control.HTML
        UIAxes                         matlab.ui.control.UIAxes
        ContextMenu                    matlab.ui.container.ContextMenu
        UnlockMenu                     matlab.ui.container.Menu
        RRMenu                         matlab.ui.container.Menu
    end

    
    properties (SetAccess = public)
        simEngine % SESimulatorEngine
    end
    
    methods (Access = public)        
        function setHtml(app, htmlContent)
            if nargin < 2 || isempty(htmlContent)
                htmlContent = '';
            end
            htmlStr = sprintf('<html><body>%s</body></html>', htmlContent);
            app.HTML.HTMLSource = htmlStr;
        end

        function setMineType(app, mineType)
            app.simEngine.setMineType(mineType);
        end

        function numMines = getNumMines(app)
            numMines = app.MineCountEditField.Value;
        end

        function numShips = getNumShips(app)
            numShips = app.NumShipsEdit.Value;
        end

        function setNumShips(app, num_ships)            
            app.simEngine.setNumShips(num_ships);
            app.refreshDisplay();
        end

        function setNumMines(app, num_mines)
            app.simEngine.setNumMines(num_mines);
            app.refreshDisplay();
        end

        function didSet = setMinefieldLayout(app, layout)
            didSet = app.simEngine.setMinefieldLayout(layout);
            app.refreshDisplay();
        end

        function setFleetBehavior(app, behavior)
            app.simEngine.setFleetBehavior(behavior);
            app.refreshDisplay();
        end

        function refreshDisplay(app)
            app.simEngine.refreshDisplay();
        end

        function disableWidgets(app)
            app.setEnableWidgets(false);
            app.AnimateCheckBox.Enable="on";
            % app.StartButton.Enable = "on";
        end

        function enableWidgets(app)
            app.setEnableWidgets(true);
        end

        function setTitle(app, titleStr, varargin)
            titleStr = sprintf(titleStr, varargin{:});
            app.UIFigure.Name = titleStr;
        end

        function setEnableWidgets(app, enabled)
            tabs = app.TabGroup.Children;
            if enabled
                enabledTag = 'on';
            else
                enabledTag = 'off';
            end
            for t=1:numel(tabs)
                tab = tabs(t);
                childrenH = tab.Children;
                for c=1:numel(childrenH)
                    h = childrenH(c);
                    if isprop(h,'enable')
                        set(h,'enable',enabledTag);
                    end
                end
            end
        end


        function updateStatistics(app)
            % TODO (Joe E) calculate current measures of effectiveness
            % Here is an example - you will need to make sure that
            % simEngine team has these methods - they will need to create
            % them if they don't
            stats = app.simEngine.getStatistics();

            %             num_mines = app.simEngine.numMines;
            %             num_ships = app.simEngine.numShips;
            %             num_mines_remaining = app.simEngine.getNumUnexplodedMines();
            %             num_ships_remaining = app.simEngine.getNumUnsunkShips();
            %
            %             moe_1 = nan;
            %             if num_ships > 0
            %                 moe_1 = 1 - num_ships_remaining / num_ships;
            %             end            
            % statsStr = sprintf('<b>MOE 1</b>%f<br>', moe_1);


            % Generate the HTML string
            htmlStr = sprintf(['<html>'...
                '<head><style>'...
                'table {font-family: Arial, sans-serif; border-collapse: collapse; width: 100%%;}'...
                'th, td {border: 1px solid #dddddd; text-align: left; padding: 8px;}'...
                'th {background-color: #f2f2f2;}'...
                '</style></head>'...
                '<body>'...
                '<h2>Statistics</h2>'...
                '<table>'...
                '<tr><th>Statistic</th><th>Value</th></tr>'...
                '<tr><td>Ships Remaining</td><td>%d</td></tr>'...
                '<tr><td>Ships Sunk</td><td>%d</td></tr>'...
                '<tr><td>Proportion of Ships Remaining</td><td>%.2f</td></tr>'...
                '<tr><td>Mines Remaining</td><td>%d</td></tr>'...                
                '<tr><td>Mines Detonated</td><td>%d</td></tr>'...
                '<tr><td>Proportion of Mines Remaining</td><td>%.2f</td></tr>'...
                '</table>'...
                '</body>'...
                '</html>'], stats.shipsRemaining, stats.shipsSunk, stats.proportionShipsRemaining, stats.minesRemaining, stats.minesDetonated, stats.proportionMinesRemaining);
            fid = 1;
                fprintf(fid, 'Ships Remaining, Ships Sunk, Proportion of Ships Remaining, Mines Remaining, Mines Detonated, Proportion of Mines Remaining\n');
                fprintf(fid, '%d, %d, %.2f, %d, %d, %.2f\n', ...
                stats.shipsRemaining, stats.shipsSunk, stats.proportionShipsRemaining, ...
                stats.minesRemaining, stats.minesDetonated, stats.proportionMinesRemaining);
            
            
            fid = fopen("results.csv", "a");
                %fprintf(fid, 'Ships Remaining, Ships Sunk, Proportion of Ships Remaining, Mines Remaining, Mines Detonated, Proportion of Mines Remaining\n');
                fprintf(fid, '%98, %d, %.2f, %d, %d, %.2f\n', ... %change %34 to %d
                stats.shipsRemaining, stats.shipsSunk, stats.proportionShipsRemaining, ...
                stats.minesRemaining, stats.minesDetonated, stats.proportionMinesRemaining);
                fclose(fid);
                

            app.setHtml(htmlStr);
            % Generate the HTML string
            htmlString = sprintf(['<html>'...
                '<head><style>'...
                'table {font-size: 12px; font-family: Arial, sans-serif; border-collapse: collapse; width: 100%%;}'...
                'th, td {border: 1px solid #dddddd; text-align: right; padding: 8px;}'...
                'th {background-color: #f2f2f2;}'...
                'h2 {font-family: Arial, sans-serif;}'...
                '</style></head>'...
                '<body>'...
                '<h2></h2>'...
                '<table>'...
                '<tr><th></th><th>Ships</th><th>Mines</th></tr>'...
                '<tr><td><b>Remaining</b></td><td>%d</td><td>%d</td></tr>'...
                '<tr><td><b>Destroyed</b></td><td>%d</td><td>%d</td></tr>'...
                '<tr><td><b>Percent Remaining</b></td><td>%.1f%%</td><td>%.1f%%</td></tr>'...
                '</table>'...
                '</body>'...
                '</html>'], ...
                stats.shipsRemaining, stats.minesRemaining, ...
                stats.shipsSunk, stats.minesDetonated, ...
                stats.proportionShipsRemaining*100, stats.proportionMinesRemaining*100);
            %app.setHtml(htmlString);
        end

        function saveStatisticsToFile(app)
            % TODO (Joe E) calculate current measures of effectiveness
            % Here is an example - you will need to make sure that
            % simEngine team has these methods - they will need to create
            % them if they don't
            stats = app.simEngine.getStatistics();

            %             num_mines = app.simEngine.numMines;
            %             num_ships = app.simEngine.numShips;
            %             num_mines_remaining = app.simEngine.getNumUnexplodedMines();
            %             num_ships_remaining = app.simEngine.getNumUnsunkShips();
            %
            %             moe_1 = nan;
            %             if num_ships > 0
            %                 moe_1 = 1 - num_ships_remaining / num_ships;
            %             end            
            % statsStr = sprintf('<b>MOE 1</b>%f<br>', moe_1);


            % Generate the HTML string
            htmlStr = sprintf(['<html>'...
                '<head><style>'...
                'table {font-family: Arial, sans-serif; border-collapse: collapse; width: 100%%;}'...
                'th, td {border: 1px solid #dddddd; text-align: left; padding: 8px;}'...
                'th {background-color: #f2f2f2;}'...
                '</style></head>'...
                '<body>'...
                '<h2>Statistics</h2>'...
                '<table>'...
                '<tr><th>Statistic</th><th>Value</th></tr>'...
                '<tr><td>Ships Remaining</td><td>%d</td></tr>'...
                '<tr><td>Ships Sunk</td><td>%d</td></tr>'...
                '<tr><td>Proportion of Ships Remaining</td><td>%.2f</td></tr>'...
                '<tr><td>Mines Remaining</td><td>%d</td></tr>'...                
                '<tr><td>Mines Detonated</td><td>%d</td></tr>'...
                '<tr><td>Proportion of Mines Remaining</td><td>%.2f</td></tr>'...
                '</table>'...
                '</body>'...
                '</html>'], stats.shipsRemaining, stats.shipsSunk, stats.proportionShipsRemaining, stats.minesRemaining, stats.minesDetonated, stats.proportionMinesRemaining);
            
            %fid = 1;
            fid = fopen("results.csv", "w"); %w to write over
                fprintf(fid, 'Ships Remaining, Ships Sunk, Proportion of Ships Remaining, Mines Remaining, Mines Detonated, Proportion of Mines Remaining\n');
                fprintf(fid, '%d, %d, %.2f, %d, %d, %.2f\n', ...
                stats.shipsRemaining, stats.shipsSunk, stats.proportionShipsRemaining, ...
                stats.minesRemaining, stats.minesDetonated, stats.proportionMinesRemaining);
                fclose(fid);
            
        end        
    end
    
    methods (Access = private)
        
        function simUpdatedCb(app, simEngineH, evt)
            app.updateStatistics();
        end
        function simCompletedCb(app, simEngineH, evt)
            app.saveStatisticsToFile();
        end
        function runCompletedCb(app, simEngineH, evt)
            app.updateStatistics();
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.simEngine = SESimulatorEngine();

            app.simEngine.addlistener('SimUpdated',@app.simUpdatedCb);
            addlistener(app.simEngine,'SimCompleted',@app.simCompletedCb);
            addlistener(app.simEngine,'MonteCarloFinished',@app.runCompletedCb);
            
            
            app.setTitle('NPS Mine Simulator');
            
            % this gets turned off occassionally, perhaps when working with
            % the Design View or when switching between MATLAB versions.
            app.UIAxes.Visible = 'on'; 
            % INITIALIZE THE UI
            app.NumRunsEdit.Value = 1;
            app.NumRunsEdit.Enable = "off";

            % Make sure we have valid dropdown values
            fleet_behaviors = app.simEngine.getValidFleetBehaviors();
            minefield_layouts = app.simEngine.getValidMinefieldLayouts();
            
            app.FleetBehaviorMenu.Items = fleet_behaviors;
            app.LayoutDropDown.Items = minefield_layouts;
        
            % Get the boundary box items and then pass these along
            % to the actual call when you are ready. 
            boundary_box = [0, 0.1, 6, 9-0.1];  
            app.simEngine.setBoundaryBox(boundary_box)
            minefield_box = [1.5, 1.5, 6-1.5*2, 9-1.5*2];
            app.simEngine.setMinefieldBox(minefield_box);
            app.simEngine.setAxesHandle(app.UIAxes);

            % This part is not complete in the simulator engine - commented
            % out (-1)
            % app.simEngine.setdamageRange(500);
            % app.simEngine.setdetectionRange(3000);

            % Sync properties of the U/I with the simulator engine            
            app.setFleetBehavior(app.FleetBehaviorMenu.Value);
            app.setMinefieldLayout(app.LayoutDropDown.Value);
            app.setNumMines(app.getNumMines());
            app.setNumShips(app.getNumShips());
            app.setHtml('Ready');
        end

        % Value changed function: NumRunsEdit
        function NumRunsEditCb(app, event)
            value = app.NumRunsEdit.Value;
            app.simEngine.setNumRuns(value);
        end

        % Value changed function: TimeLimitsEditField
        function TimeLimitsEditFieldCb(app, event)
            value = app.TimeLimitsEditField.Value;
            app.simEngine.setTimeLimit(value);
        end

        % Button pushed function: StartButton
        function StartButtonPushedCb(app, event)
            %PENDING TO ADD THIS FUNCTION TO RUN THE SIMULATION
            app.disableWidgets();
            % app.setHtml('Running');
            app.simEngine.run();
            app.updateStatistics();            
            app.enableWidgets();
        end

        % Value changed function: NumShipsEdit
        function NumShipsEditValueChanged(app, event)
            value = app.NumShipsEdit.Value;
            app.setNumShips(value);
            % fprintf(1,'Number of ships changed to: %d\n', value);
        end

        % Value changed function: FleetBehaviorMenu
        function FleetBehaviorMenuValueChanged(app, event)
            value = app.FleetBehaviorMenu.Value;
            app.setFleetBehavior(value);
            % fprintf(1,'Fleet behavior is now: %s\n', value);
        end

        % Value changed function: MineCountEditField
        function MineCountEditFieldValueChanged(app, event)
            value = app.MineCountEditField.Value;
            app.setNumMines(value);
        end

        % Value changed function: LayoutDropDown
        function LayoutDropDownCb(app, event)
            value = app.LayoutDropDown.Value;
            app.setMinefieldLayout(value);            
        end

        % Menu selected function: UnlockMenu
        function UnlockMenuSelected(app, event)
            app.enableWidgets();
        end

        % Menu selected function: RRMenu
        function RRMenuSelected(app, event)
            web('https://youtu.be/dQw4w9WgXcQ?si=P4Xfbnwc87U4JXh8');
        end

        % Value changed function: DisplayShipHeadingCheckBox
        function DisplayShipHeadingCheckBoxValueChanged(app, event)
            value = app.DisplayShipHeadingCheckBox.Value;
            app.simEngine.displayShipHeadings(value);
        end

        % Value changed function: MineTypeDropDown
        function MineTypeDropDownValueChanged(app, event)
            value = app.MineTypeDropDown.Value;
            app.setMineType(value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            app.UIAxes.XLim = [0 6];
            app.UIAxes.YLim = [0 9];
            app.UIAxes.XTick = [0 1 2 3 4 5 6];
            app.UIAxes.YTick = [0 1 2 3 4 5 6 7 8 9];
            app.UIAxes.YTickLabel = {'0'; '1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'};
            app.UIAxes.Visible = 'off';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.HTML = uihtml(app.GridLayout2);
            app.TabGroup = uitabgroup(app.GridLayout2);
            app.SimulationTab = uitab(app.TabGroup);
            app.SimulationTab.Title = 'Simulation';
            app.RunsLabel = uilabel(app.SimulationTab);
            app.RunsLabel.Text = 'Number of Runs';
            app.NumRunsEdit = uieditfield(app.SimulationTab, 'numeric');
            app.NumRunsEdit.ValueChangedFcn = createCallbackFcn(app, @NumRunsEditCb, true);
            app.NumRunsEdit.Value = 1;
            app.TimeLimitsEditFieldLabel = uilabel(app.SimulationTab);
            app.TimeLimitsEditFieldLabel.Text = 'Time Limit (s)';
            app.TimeLimitsEditField = uieditfield(app.SimulationTab, 'numeric');
            app.TimeLimitsEditField.ValueChangedFcn = createCallbackFcn(app, @TimeLimitsEditFieldCb, true);
            app.TimeLimitsEditField.Value = 30;
            app.StartButton = uibutton(app.SimulationTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushedCb, true);
            app.StartButton.Text = 'Start';
            app.AnimateCheckBox = uicheckbox(app.SimulationTab);
            app.AnimateCheckBox.Text = 'Animate';
            app.AnimateCheckBox.Value = true;
            app.FleetTab = uitab(app.TabGroup);
            app.FleetTab.Title = 'Fleet';
            app.ShipsLabel = uilabel(app.FleetTab);
            app.ShipsLabel.Text = 'Ships';
            app.NumShipsEdit = uieditfield(app.FleetTab, 'numeric');
            app.NumShipsEdit.ValueChangedFcn = createCallbackFcn(app, @NumShipsEditValueChanged, true);
            app.NumShipsEdit.Value = 10;
            app.BehaviorDropDown_2Label = uilabel(app.FleetTab);
            app.BehaviorDropDown_2Label.Text = 'Behavior';
            app.FleetBehaviorMenu = uidropdown(app.FleetTab);
            app.FleetBehaviorMenu.Items = {'Kamikaze', 'Random', 'Channelized'};
            app.FleetBehaviorMenu.ValueChangedFcn = createCallbackFcn(app, @FleetBehaviorMenuValueChanged, true);
            app.FleetBehaviorMenu.Value = 'Kamikaze';
            app.DisplayShipHeadingCheckBox = uicheckbox(app.FleetTab);
            app.DisplayShipHeadingCheckBox.ValueChangedFcn = createCallbackFcn(app, @DisplayShipHeadingCheckBoxValueChanged, true);
            app.DisplayShipHeadingCheckBox.Text = 'Display Ship Heading';
            app.DisplayShipHeadingCheckBox.Value = true;
            app.MinefieldTab = uitab(app.TabGroup);
            app.MinefieldTab.Title = 'Minefield';
            app.MineCountEditFieldLabel = uilabel(app.MinefieldTab);
            app.MineCountEditFieldLabel.Text = 'Mine Count';
            app.MineCountEditField = uieditfield(app.MinefieldTab, 'numeric');
            app.MineCountEditField.ValueChangedFcn = createCallbackFcn(app, @MineCountEditFieldValueChanged, true);
            app.MineCountEditField.Value = 10;
            app.DamageRangemEditFieldLabel = uilabel(app.MinefieldTab);
            app.DamageRangemEditFieldLabel.Text = 'Damage Range (m)';
            app.DamageRangemEditField = uieditfield(app.MinefieldTab, 'numeric');
            app.DamageRangemEditField.Value = 500;
            app.LayoutDropDownLabel = uilabel(app.MinefieldTab);
            app.LayoutDropDownLabel.Text = 'Layout';
            app.LayoutDropDown = uidropdown(app.MinefieldTab);
            app.LayoutDropDown.Items = {'Random', 'Smart', 'Dumb'};
            app.LayoutDropDown.ValueChangedFcn = createCallbackFcn(app, @LayoutDropDownCb, true);
            app.LayoutDropDown.Value = 'Smart';
            app.DetectionRangemEditFieldLabel = uilabel(app.MinefieldTab);
            app.DetectionRangemEditFieldLabel.Text = 'Detection Range (m)';
            app.DetectionRangemEditField = uieditfield(app.MinefieldTab, 'numeric');
            app.DetectionRangemEditField.Value = 500;
            app.MineTypeDropDownLabel = uilabel(app.MinefieldTab);
            app.MineTypeDropDownLabel.Text = 'Mine Type';
            app.MineTypeDropDown = uidropdown(app.MinefieldTab);
            app.MineTypeDropDown.Items = {'Mobile', 'Static'};
            app.MineTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @MineTypeDropDownValueChanged, true);
            app.MineTypeDropDown.Value = 'Mobile';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.UnlockMenu = uimenu(app.ContextMenu);
            app.UnlockMenu.MenuSelectedFcn = createCallbackFcn(app, @UnlockMenuSelected, true);
            app.UnlockMenu.Text = 'Unlock';
            app.RRMenu = uimenu(app.ContextMenu);
            app.RRMenu.MenuSelectedFcn = createCallbackFcn(app, @RRMenuSelected, true);
            app.RRMenu.Text = 'RR';
            % Assign app.ContextMenu
            app.GridLayout2.ContextMenu = app.ContextMenu;
            app.TabGroup.ContextMenu = app.ContextMenu;
            app.SimulationTab.ContextMenu = app.ContextMenu;
            app.UIAxes.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mobilemineSim

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end