classdef plot_app_gabino < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        RunItButton  matlab.ui.control.Button
        UIAxes       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunItButton
        function RunItButtonPushed(app, event)
            dx = 0.5;
            dy = 0.25


            y = ([-30,-10,10,30]);
            x = ([0,0,0,0]);

            mine = SEMine(0,0,app.UIAxes);

            l = plot(app.UIAxes,x,y);
           
            l.Color = ['red']
            l.LineStyle = 'none';
            l.LineWidth = 5;
            l.Marker = 'o';

            app.UIAxes.XLim = [-40 40];
            app.UIAxes.YLim = [-40 40];

            %a.Box = 'on';
            fps = 60;
            k=0;

            for k = 1:1000
                if l.XData(1) > app.UIAxes.XLim(2)
                    dx = -dx
                else
                    if l.XData(1) < app.UIAxes.XLim(1)
                        dx = -dx
                    end
                end

                if mine.pos_y >  app.UIAxes.YLim(2)
                    dy = -dy
                else
                    if mine.pos_y < app.UIAxes.YLim(1)
                        dy = -dy
                    end
                end

                l.XData = l.XData + dx;
                pause(1/fps);
                mine.pos_x = mine.pos_x + dx;
                mine.pos_y = mine.pos_y + dy;
                mine.updateDisplay;


            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.RunItButton = uibutton(app.UIFigure, 'push');
            app.RunItButton.ButtonPushedFcn = createCallbackFcn(app, @RunItButtonPushed, true);
            app.RunItButton.Text = 'Run It!';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = plot_app_gabino

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end